classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        MoPhongNhoePanel           matlab.ui.container.Panel
        TaoNhoeButton              matlab.ui.control.Button
        ThetaEditField             matlab.ui.control.NumericEditField
        GcthetaEditFieldLabel      matlab.ui.control.Label
        LenEditField               matlab.ui.control.NumericEditField
        dilenEditFieldLabel        matlab.ui.control.Label
        MoPhongNhieuPanel          matlab.ui.container.Panel
        SPDropDown                 matlab.ui.control.DropDown
        NhiuSPDropDownLabel        matlab.ui.control.Label
        GaussianDropDown           matlab.ui.control.DropDown
        NhiuGaussianDropDownLabel  matlab.ui.control.Label
        TaiDuLieuPanel             matlab.ui.container.Panel
        TaiAnhButton               matlab.ui.control.Button
        UIAxes3_KhoiPhuc           matlab.ui.control.UIAxes
        UIAxes_Loi                 matlab.ui.control.UIAxes
        UIAxes_Goc                 matlab.ui.control.UIAxes
    end

    properties (Access = private)
    I_goc % Chứa ảnh gốc ban đầu
    I_loi % Chứa ảnh sau khi bị nhiễu/nhòe
end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: TaiAnhButton
        function TaiAnhButtonPushed(app, event)
            % Mở hộp thoại chọn file ảnh từ máy tính
            [file, path] = uigetfile({'*.jpg;*.png;*.bmp;*.tif', 'Image Files'});
            if isequal(file, 0)
                return;
            end
            img_path = fullfile(path, file);

            % your code here
            app.I_goc = imread(img_path);
            if size(app.I_goc, 3) == 3
                app.I_goc = rgb2gray(app.I_goc);
            end
            imshow(app.I_goc, 'Parent', app.UIAxes_Goc);
            title(app.UIAxes_Goc, 'Ảnh Gốc');
            % end your code here
        end

        % Value changed function: GaussianDropDown
        function GaussianDropDownValueChanged(app, event)
            value = app.GaussianDropDown.Value;
            variance_str = app.GaussianDropDown.Value; % Lấy giá trị chữ từ Dropdown
            variance = str2double(variance_str);       % Ép kiểu sang số

            % your code here
            app.I_loi = imnoise(app.I_goc, 'gaussian', 0, variance);
            imshow(app.I_loi, 'Parent', app.UIAxes_Loi);
            title(app.UIAxes_Loi, 'Ảnh Lỗi (Gaussian)');
            % end your code here
        end

        % Value changed function: SPDropDown
        function SPDropDownValueChanged(app, event)
            value = app.SPDropDown.Value;
            density_str = app.SPDropDown.Value;
            density = str2double(density_str);

            % your code here
            app.I_loi = imnoise(app.I_goc, 'salt & pepper', density);
            imshow(app.I_loi, 'Parent', app.UIAxes_Loi);
            title(app.UIAxes_Loi, 'Ảnh Lỗi (Salt & Pepper)');
            % end your code here
        end

        % Button pushed function: TaoNhoeButton
        function TaoNhoeButtonPushed(app, event)
            % Lấy thông số độ dài và góc từ ô nhập
            len = app.LenEditField.Value;
            theta = app.ThetaEditField.Value;

            % your code here
            kernel = fspecial('motion', len, theta);
            app.I_loi = imfilter(app.I_goc, kernel, 'replicate');
            imshow(app.I_loi, 'Parent', app.UIAxes_Loi);
            title(app.UIAxes_Loi, 'Ảnh Lỗi (Motion Blur)');
            % end your code here
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes_Goc = uiaxes(app.UIFigure);
            title(app.UIAxes_Goc, 'Ảnh Gốc')
            zlabel(app.UIAxes_Goc, 'Z')
            app.UIAxes_Goc.XTick = [];
            app.UIAxes_Goc.YTick = [];
            app.UIAxes_Goc.Visible = 'off';
            app.UIAxes_Loi = uiaxes(app.UIFigure);
            title(app.UIAxes_Loi, 'Ảnh Lỗi')
            zlabel(app.UIAxes_Loi, 'Z')
            app.UIAxes_Loi.XTick = [];
            app.UIAxes_Loi.YTick = [];
            app.UIAxes_Loi.Visible = 'off';
            app.UIAxes3_KhoiPhuc = uiaxes(app.UIFigure);
            title(app.UIAxes3_KhoiPhuc, 'Ảnh Khôi Phục')
            zlabel(app.UIAxes3_KhoiPhuc, 'Z')
            app.UIAxes3_KhoiPhuc.XTick = [];
            app.UIAxes3_KhoiPhuc.YTick = [];
            app.UIAxes3_KhoiPhuc.Visible = 'off';
            app.TaiDuLieuPanel = uipanel(app.UIFigure);
            app.TaiDuLieuPanel.Title = 'Tải Dữ Liệu';
            app.TaiAnhButton = uibutton(app.TaiDuLieuPanel, 'push');
            app.TaiAnhButton.ButtonPushedFcn = createCallbackFcn(app, @TaiAnhButtonPushed, true);
            app.TaiAnhButton.Text = 'Tải Ảnh';
            app.MoPhongNhieuPanel = uipanel(app.UIFigure);
            app.MoPhongNhieuPanel.Title = 'Mô Phỏng Nhiễu';
            app.NhiuGaussianDropDownLabel = uilabel(app.MoPhongNhieuPanel);
            app.NhiuGaussianDropDownLabel.Text = 'Nhiễu Gaussian';
            app.GaussianDropDown = uidropdown(app.MoPhongNhieuPanel);
            app.GaussianDropDown.Items = {'0.01', '0.05'};
            app.GaussianDropDown.ValueChangedFcn = createCallbackFcn(app, @GaussianDropDownValueChanged, true);
            app.GaussianDropDown.Value = '0.01';
            app.NhiuSPDropDownLabel = uilabel(app.MoPhongNhieuPanel);
            app.NhiuSPDropDownLabel.Text = 'Nhiễu S&P';
            app.SPDropDown = uidropdown(app.MoPhongNhieuPanel);
            app.SPDropDown.Items = {'0.05', '0.1'};
            app.SPDropDown.ValueChangedFcn = createCallbackFcn(app, @SPDropDownValueChanged, true);
            app.SPDropDown.Value = '0.05';
            app.MoPhongNhoePanel = uipanel(app.UIFigure);
            app.MoPhongNhoePanel.Title = 'Mô Phỏng Nhòe';
            app.dilenEditFieldLabel = uilabel(app.MoPhongNhoePanel);
            app.dilenEditFieldLabel.Text = 'Độ dài (len)';
            app.LenEditField = uieditfield(app.MoPhongNhoePanel, 'numeric');
            app.GcthetaEditFieldLabel = uilabel(app.MoPhongNhoePanel);
            app.GcthetaEditFieldLabel.Text = 'Góc (theta)';
            app.ThetaEditField = uieditfield(app.MoPhongNhoePanel, 'numeric');
            app.TaoNhoeButton = uibutton(app.MoPhongNhoePanel, 'push');
            app.TaoNhoeButton.ButtonPushedFcn = createCallbackFcn(app, @TaoNhoeButtonPushed, true);
            app.TaoNhoeButton.Text = 'Tạo Nhòe';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end